classdef RMNPulsoFormaLeeSSC_GN_DL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppSSCPulsoRMN_GeneraS          matlab.ui.Figure
        EnviarButton                    matlab.ui.control.Button
        PanelDICom                      matlab.ui.container.Panel
        BorraSDerIntButton              matlab.ui.control.Button
        PanelInt                        matlab.ui.container.Panel
        GeneraIntegralButton            matlab.ui.control.Button
        PanelSSC                        matlab.ui.container.Panel
        VerExtremosLabel                matlab.ui.control.Label
        DeciEditField                   matlab.ui.control.NumericEditField
        DecimalesLabel                  matlab.ui.control.Label
        CopyClipButton                  matlab.ui.control.Button
        LEDSSC                          matlab.ui.control.Lamp
        HALTLabel                       matlab.ui.control.Label
        TableSSC                        matlab.ui.control.Table
        SWVerExtInt                     matlab.ui.control.Switch
        IntegralLabel                   matlab.ui.control.Label
        rnExtEditField                  matlab.ui.control.NumericEditField
        RadiodeDeteccindeExtremosLabel  matlab.ui.control.Label
        SWVerExtDer                     matlab.ui.control.Switch
        DerivadaLabel                   matlab.ui.control.Label
        SWVerExtIn                      matlab.ui.control.Switch
        EntradaLabel                    matlab.ui.control.Label
        CalculaSSCButton                matlab.ui.control.Button
        PanelDetPul                     matlab.ui.container.Panel
        tfinDisp                        matlab.ui.control.NumericEditField
        tfinEditFieldLabel              matlab.ui.control.Label
        tiniDisp                        matlab.ui.control.NumericEditField
        tiniLabel                       matlab.ui.control.Label
        DetPulButton                    matlab.ui.control.Button
        TolDefPulKnobDIn                matlab.ui.control.NumericEditField
        TolDefPulKnobAIn                matlab.ui.control.Knob
        ToleranciaDeteccinPulsoLabel    matlab.ui.control.Label
        PanelSIn                        matlab.ui.container.Panel
        SalirButton                     matlab.ui.control.Button
        BorraSInButton                  matlab.ui.control.Button
        NInEditField                    matlab.ui.control.NumericEditField
        NrodepuntosEditFieldLabel       matlab.ui.control.Label
        EnvolventeDropDown              matlab.ui.control.DropDown
        EnvolventeDropDownLabel         matlab.ui.control.Label
        GeneraEntradaButton             matlab.ui.control.Button
        PanelDer                        matlab.ui.container.Panel
        SwFMa                           matlab.ui.control.Switch
        ActivarFiltroMedianaLabel       matlab.ui.control.Label
        rFMaEditField                   matlab.ui.control.NumericEditField
        RadiodeFiltroMedianaLabel       matlab.ui.control.Label
        GeneraDerivadaButton            matlab.ui.control.Button
        PanelOffR                       matlab.ui.container.Panel
        FrecOffKnobD                    matlab.ui.control.NumericEditField
        FrecOffLabel                    matlab.ui.control.Label
        SActivaFOff                     matlab.ui.control.RockerSwitch
        ActivarFrecOffResonanciaLabel   matlab.ui.control.Label
        FaseOffKnob                     matlab.ui.control.DiscreteKnob
        FaseLabel                       matlab.ui.control.Label
        LEDOff                          matlab.ui.control.Lamp
        FrecOffKnobA                    matlab.ui.control.Knob
        UIAxesSDerInt                   matlab.ui.control.UIAxes
        UIAxesSIn                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)            
        %%%%% Señal de entrada o pulso:
        NIn % Nro. de puntos de la señal o pulso
        dtIn % Paso de tiempo del pulso
        tIn % Valores de tiempo del pulso

        t0In % Tiempo de origen (señal completa)
        tWIn % Ancho temporal del pulso (señal completa)
        
        SignalIn % Señal
        EnvolIn % Envolvente
        DerSignalIn % Derivada
        IntSignalIn % Integral
        FAux % Función auxiliar
        
        %%%%% Parámetros:
        tolDefPulIn % Tolerancia para definir el inicio y fin del pulso
        niniPIn % Posición inicial del pulso
        nfinPIn % Posición final del pulso
        rnext % Cantidad de elementos por punto para buscar los extremos
        % Extremos de la Señal o Pulso:
        posextIn % Posición en la Señal o Pulso
        posextDerIn % Posición en la Derivada
        posextIntIn % Posición en la Integral
        DatosSSC % Tabla de valores de los parámetros SSC
    end
    
    properties (Access = public)
        sapp % Aplicación a llamar
        sapp2 % Aplicación a llamar nro. 2
        
        %%%%% Señal de entrada completa (Full):
        FNIn % Nro. de puntos
        FdtIn % Paso de tiempo
        FtIn % Valores de tiempo
        tipoEnv % Tipo de envolvente
        ActivaOff % Determina si está activa o no la portadora en Off-Resonancia
        FrecOff % Frecuencia de la portadora en Off-Resonancia
        FaseOff % Fase de la portadora en Off-Resonancia
        
        FSignalIn % Señal completa     
        GrillaSSC % Arreglo con los valores SSC
        Mt,Dt,Ma,Da % Parámetros SSC
        Ma1,Da2,Da3,Mt1,Dt2,Dt3 % Parámetros SSC extendidos
    end
    
    methods (Access = private)

        function ActualizaEntrada(app)
            app.FSignalIn = [];
            app.EnvolIn = [];
            CargaEnvolIn(app);
            if strcmp(app.SActivaFOff.Value,'On')
                app.FSignalIn = app.EnvolIn.*cos(2*pi*app.FrecOff*(app.FtIn-app.t0In)+pi*app.FaseOff/180);
            else
                app.FSignalIn = app.EnvolIn;
            end        
        end
        
        function CargaEnvolIn(app)
            GFacW = 0.3;
            GFrec = 10;
            switch lower(app.tipoEnv)
                case 'sinc'
                    app.EnvolIn = sinc(10*(app.FtIn-app.t0In)).*rectpuls(app.FtIn-app.t0In,app.tWIn);
                case 'gaussiana osc. 0º'
                    app.EnvolIn = gauspuls((app.FtIn-app.t0In),GFrec,GFacW/app.tWIn);
                case 'gaussiana osc. 90º'
                    [~,app.EnvolIn] = gauspuls((app.FtIn-app.t0In),GFrec,GFacW/app.tWIn);
                case 'gaussiana'
                    [~,~,app.EnvolIn] = gauspuls((app.FtIn-app.t0In),GFrec,GFacW/app.tWIn);
                case 'chirp'
                    app.EnvolIn = chirp((app.FtIn-(app.t0In-app.tWIn/2)),0,app.t0In+app.tWIn/2,10).*...
                        rectpuls(app.FtIn-app.t0In,app.tWIn);
            end
        end     
        
        function DefinePulso(app)
            DetectaPulso(app);
            app.tiniDisp.Value = app.FtIn(app.niniPIn);
            app.tfinDisp.Value = app.FtIn(app.nfinPIn);
            app.NIn = app.nfinPIn - app.niniPIn + 1;
            
            app.SignalIn = [];
            app.SignalIn = app.FSignalIn(app.niniPIn:app.nfinPIn);
            %%%%%% Normaliza amplitud
            app.SignalIn = app.SignalIn/max(abs(app.SignalIn));
            %%%%%%
            
            app.tIn = [];
            %%%%%% Normaliza tiempo 
            app.dtIn = 1/(app.NIn-1);
            app.tIn = (0:(app.NIn-1))*app.dtIn;           
            %%%%%%                 
        end
        
        function DetectaExtremos(app)
            if ~isempty(app.SignalIn)
                app.posextIn = [];
                app.posextIn = Calcula_Extremos(app.SignalIn,app.rnext);
            end
            if ~isempty(app.DerSignalIn)
                app.posextDerIn = [];
                app.posextDerIn = Calcula_Extremos(app.DerSignalIn,app.rnext);
            end
            if ~isempty(app.SignalIn)
                app.posextIntIn = [];
                app.posextIntIn = Calcula_Extremos(app.IntSignalIn,app.rnext);
            end
        end
        
        function DetectaPulso(app)
            [app.niniPIn, app.nfinPIn] = Extrae_Pulso(app.FSignalIn,app.tolDefPulIn/100);
        end
        
        function GeneraDerivada(app)
            app.DerSignalIn = [];
            CalcDerivada(app);                      
            if strcmp(app.SwFMa.Value,'On')
                app.DerSignalIn = Filtro0_Mediana(app.DerSignalIn,app.rFMaEditField.Value);
            end
            app.DerSignalIn = app.DerSignalIn/max(abs(app.DerSignalIn));
        end
                
        function GeneraIntegral(app)
            app.IntSignalIn = [];
            CalcIntegral(app);
            app.IntSignalIn = app.IntSignalIn/max(abs(app.IntSignalIn));           
        end
        
        function CalcDerivada(app)
            %%%%%% Método 1:
            app.FAux = [];
            app.FAux = app.SignalIn(2:app.NIn);
            app.FAux(app.NIn) = 0;
            app.DerSignalIn = app.FAux-app.SignalIn;
            %%%%%%
            %%%%%% Método 2
            % for n = 1:(app.NIn-1)
            %     app.DerSignalIn(n) = (app.SignalIn(n+1)-app.SignalIn(n));
            % end
            % app.DerSignalIn(app.NIn) = -app.SignalIn(app.NIn);
            %%%%%%
        end

        function CalcIntegral(app)
            %%%%%% Método 1:
            app.IntSignalIn = cumsum(app.SignalIn);    
            %%%%%%
            %%%%%% Método 2:
            % ValInt = 0;
            % for n = 1:app.NIn
            %     ValInt = ValInt + app.SignalIn(n);
            %     app.IntSignalIn(n) = ValInt;
            % end           
            %%%%%%
        end
        
        function GeneraSSC(app)
            DetectaExtremos(app);
            if ~isempty(app.posextIn)
                nfun = 2;
                Calc_SSC(app,app.tIn,app.SignalIn,nfun,app.posextIn);
                app.DatosSSC.ValS = [app.Mt(nfun);app.Dt(nfun);app.Ma(nfun);app.Da(nfun);...
                    app.Ma1(nfun);app.Da2(nfun);app.Da3(nfun);app.Mt1(nfun);app.Dt2(nfun);app.Dt3(nfun)];
            end
            if ~isempty(app.posextDerIn)
                nfun = 3;
                Calc_SSC(app,app.tIn,app.DerSignalIn,nfun,app.posextDerIn);
                app.DatosSSC.ValD = [app.Mt(nfun);app.Dt(nfun);app.Ma(nfun);app.Da(nfun);...
                    app.Ma1(nfun);app.Da2(nfun);app.Da3(nfun);app.Mt1(nfun);app.Dt2(nfun);app.Dt3(nfun)];
            end
            if ~isempty(app.posextIntIn)
                nfun = 1;
                Calc_SSC(app,app.tIn,app.IntSignalIn,nfun,app.posextIntIn);
                app.DatosSSC.ValI = [app.Mt(nfun);app.Dt(nfun);app.Ma(nfun);app.Da(nfun);...
                    app.Ma1(nfun);app.Da2(nfun);app.Da3(nfun);app.Mt1(nfun);app.Dt2(nfun);app.Dt3(nfun)];                    
            end
            app.GrillaSSC = [app.DatosSSC.ValI,app.DatosSSC.ValS,app.DatosSSC.ValD];
        end

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FNIn = app.NInEditField.Value + 1;
            app.FdtIn = 1/(app.FNIn-1);
            app.FtIn = (0:(app.FNIn-1))*app.FdtIn; 
            app.NIn = app.FNIn;
            app.dtIn = app.FdtIn;
            app.tIn = app.FtIn;

            app.tipoEnv = app.EnvolventeDropDown.Value;
            app.t0In = 0.5;
            app.tWIn = 0.6;
            
            app.FSignalIn = [];
            app.SignalIn = [];
            app.EnvolIn = [];
            app.DerSignalIn = [];
            app.IntSignalIn = [];
            % app.FrecOff = 100;
            % app.FaseOff = 0;
            % app.FrecOffKnobA.Value = app.FrecOff;
            % app.FrecOffKnobD.Value = app.FrecOff;
            % app.FaseOffKnob.Value = '0';
            app.ActivaOff = app.SActivaFOff.Value;
            app.FrecOff = app.FrecOffKnobD.Value;
            app.FrecOffKnobA.Value = app.FrecOffKnobD.Value;
            app.FaseOff = str2double(app.FaseOffKnob.Value);
            
            % app.tolDefPulIn = 2; 
            % app.TolDefPulKnobAIn.Value = app.tolDefPulIn;
            % app.TolDefPulKnobDIn.Value = app.tolDefPulIn;
            app.tolDefPulIn = app.TolDefPulKnobDIn.Value; 
            app.TolDefPulKnobAIn.Value = app.TolDefPulKnobDIn.Value;

            app.niniPIn = 1;
            app.nfinPIn = app.NIn;
            % app.rnext = 5;
            app.rnext = app.rnExtEditField.Value;
            app.posextIn = [];
            app.posextDerIn = [];
            app.posextIntIn = [];
            app.Mt = []; 
            app.Dt = []; 
            app.Ma = []; 
            app.Da = []; 
            app.Ma1 = []; 
            app.Da2 = []; 
            app.Da3 = [];
            app.Mt1 = []; 
            app.Dt2 = []; 
            app.Dt3 = [];
            
            app.DatosSSC = table('Size',[10,4],'VariableTypes',{'string','double','double','double'},...
                'VariableNames',{'NombreSSC','ValI','ValS','ValD'});
            app.DatosSSC.NombreSSC = ["Mt";"Dt";"Ma";"Da";"Ma1";"Da2";"Da3";"Mt1";"Dt2";"Dt3"];
            app.TableSSC.Data = app.DatosSSC;
            app.GrillaSSC = [app.DatosSSC.ValI,app.DatosSSC.ValS,app.DatosSSC.ValD];
            
            app.EnviarButton.Enable = 'on';
            app.sapp = [];
            app.sapp2 = [];
        end

        % Value changed function: NInEditField
        function NInEditFieldValueChanged(app, event)
            app.FNIn = app.NInEditField.Value + 1;
            app.FdtIn = 1/(app.FNIn-1);
            app.FtIn = (0:(app.FNIn-1))*app.FdtIn;           
        end

        % Button pushed function: GeneraEntradaButton
        function GeneraEntradaButtonPushed(app, event)
            ActualizaEntrada(app);
            plot(app.UIAxesSIn,app.FtIn,app.FSignalIn,'r','LineWidth',1);
            xlim(app.UIAxesSIn,[0,1]);
            ylim(app.UIAxesSIn,[-1,1]);
        end

        % Value changed function: EnvolventeDropDown
        function EnvolventeDropDownValueChanged(app, event)
            app.tipoEnv = app.EnvolventeDropDown.Value; 
        end

        % Button pushed function: GeneraDerivadaButton
        function GeneraDerivadaButtonPushed(app, event)
            if ~isempty(app.SignalIn)
                GeneraDerivada(app);
                plot(app.UIAxesSDerInt,app.tIn,app.DerSignalIn,'b','LineWidth',1);
                xlim(app.UIAxesSDerInt,[0,1]);
                ylim(app.UIAxesSDerInt,[-1,1]);
            end
        end

        % Button pushed function: GeneraIntegralButton
        function GeneraIntegralButtonPushed(app, event)
            if ~isempty(app.SignalIn)
                GeneraIntegral(app);
                plot(app.UIAxesSDerInt,app.tIn,app.IntSignalIn,'g','LineWidth',1);
                xlim(app.UIAxesSDerInt,[0,1]);
                ylim(app.UIAxesSDerInt,[-1,1]);
            end
        end

        % Button pushed function: BorraSDerIntButton
        function BorraSDerIntButtonPushed(app, event)
            cla(app.UIAxesSDerInt);
        end

        % Value changing function: FrecOffKnobA
        function FrecOffKnobAValueChanging(app, event)
            app.FrecOffKnobD.Value = event.Value;          
        end

        % Value changed function: FrecOffKnobA
        function FrecOffKnobAValueChanged(app, event)
            app.FrecOff = app.FrecOffKnobA.Value;
            app.FrecOffKnobD.Value = app.FrecOff;
        end

        % Value changed function: FrecOffKnobD
        function FrecOffKnobDValueChanged(app, event)
            app.FrecOff = app.FrecOffKnobD.Value;
            app.FrecOffKnobA.Value = app.FrecOff;
        end

        % Value changed function: FaseOffKnob
        function FaseOffKnobValueChanged(app, event)
            app.FaseOff = str2double(app.FaseOffKnob.Value);
        end

        % Value changed function: SActivaFOff
        function SActivaFOffValueChanged(app, event)
            app.ActivaOff = app.SActivaFOff.Value;
            if strcmp(app.ActivaOff,'On')
                app.LEDOff.Color = [0,1,0];
            else
                app.LEDOff.Color = [0.25,0.35,0];
            end            
        end

        % Value changing function: TolDefPulKnobAIn
        function TolDefPulKnobAInValueChanging(app, event)
            app.TolDefPulKnobDIn.Value = event.Value;            
        end

        % Value changed function: TolDefPulKnobAIn
        function TolDefPulKnobAInValueChanged(app, event)
            app.tolDefPulIn = app.TolDefPulKnobAIn.Value;
            app.TolDefPulKnobDIn.Value = app.tolDefPulIn;
        end

        % Value changed function: TolDefPulKnobDIn
        function TolDefPulKnobDInValueChanged(app, event)
            app.tolDefPulIn = app.TolDefPulKnobDIn.Value;
            app.TolDefPulKnobAIn.Value = app.tolDefPulIn;
        end

        % Button pushed function: DetPulButton
        function DetPulButtonPushed(app, event)
            if ~isempty(app.FSignalIn)
                DefinePulso(app);                
                plot(app.UIAxesSIn,app.tIn,app.SignalIn,'k','LineWidth',1);
                xlim(app.UIAxesSIn,[0,1]);
                ylim(app.UIAxesSIn,[-1,1]);
            end            
        end

        % Button pushed function: BorraSInButton
        function BorraSInButtonPushed(app, event)
            cla(app.UIAxesSIn);
        end

        % Value changed function: rnExtEditField
        function rnExtEditFieldValueChanged(app, event)
            app.rnext = app.rnExtEditField.Value;
        end

        % Button pushed function: CalculaSSCButton
        function CalculaSSCButtonPushed(app, event)
            app.LEDSSC.Color = [1,0,0];
            GeneraSSC(app);
            if ~isempty(app.posextIn) && strcmp(app.SWVerExtIn.Value,'SI')
                plot(app.UIAxesSIn,app.tIn(app.posextIn),app.SignalIn(app.posextIn),...
                    'o','LineWidth',2,'MarkerEdgeColor','m','MarkerSize',8);
                xlim(app.UIAxesSIn,[0,1]);
                ylim(app.UIAxesSIn,[-1,1]);
            end
            if ~isempty(app.posextDerIn) && strcmp(app.SWVerExtDer.Value,'SI')
                plot(app.UIAxesSDerInt,app.tIn(app.posextDerIn),app.DerSignalIn(app.posextDerIn),...
                    'o','LineWidth',2,'MarkerEdgeColor','c','MarkerSize',8);
                xlim(app.UIAxesSDerInt,[0,1]);
                ylim(app.UIAxesSDerInt,[-1,1]);
            end
            if ~isempty(app.posextIntIn) && strcmp(app.SWVerExtInt.Value,'SI')
                plot(app.UIAxesSDerInt,app.tIn(app.posextIntIn),app.IntSignalIn(app.posextIntIn),...
                    'o','LineWidth',2,'MarkerEdgeColor','y','MarkerSize',8);
                xlim(app.UIAxesSDerInt,[0,1]);
                ylim(app.UIAxesSDerInt,[-1,1]);
            end
            app.TableSSC.Data = app.DatosSSC;
            app.LEDSSC.Color = [0.4,0.1,0.2];
        end

        % Button pushed function: CopyClipButton
        function CopyClipButtonPushed(app, event)
            clipboard('copy', round(app.GrillaSSC,app.DeciEditField.Value));
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            app.EnviarButton.Enable = 'off';            
            app.sapp = RMNPulsoRealSSC_GN_DL(app);
        end

        % Callback function: AppSSCPulsoRMN_GeneraS, SalirButton
        function AppSSCPulsoRMN_GeneraSCloseRequest(app, event)
            if ~isempty(app.sapp)
                if ~isempty(app.sapp2)
                    delete(app.sapp2);
                end
                delete(app.sapp);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppSSCPulsoRMN_GeneraS = uifigure('Visible', 'off');
            app.AppSSCPulsoRMN_GeneraS.Name = 'SSC Pulso RMN - Genera Señal';
            app.AppSSCPulsoRMN_GeneraS.CloseRequestFcn = createCallbackFcn(app, @AppSSCPulsoRMN_GeneraSCloseRequest, true);
            app.UIAxesSIn = uiaxes(app.AppSSCPulsoRMN_GeneraS);
            title(app.UIAxesSIn, 'Señal de Entrada')
            xlabel(app.UIAxesSIn, 't')
            ylabel(app.UIAxesSIn, 'Amplitud Normalizada')
            app.UIAxesSIn.YLim = [-1 1];
            app.UIAxesSIn.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesSIn.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxesSDerInt = uiaxes(app.AppSSCPulsoRMN_GeneraS);
            title(app.UIAxesSDerInt, 'Derivada (azul) / Integral (verde)')
            xlabel(app.UIAxesSDerInt, 't')
            ylabel(app.UIAxesSDerInt, 'Amplitud Normalizada')
            app.UIAxesSDerInt.YLim = [-1 1];
            app.UIAxesSDerInt.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesSDerInt.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.PanelOffR = uipanel(app.AppSSCPulsoRMN_GeneraS);
            app.FrecOffKnobA = uiknob(app.PanelOffR, 'continuous');
            app.FrecOffKnobA.ValueChangedFcn = createCallbackFcn(app, @FrecOffKnobAValueChanged, true);
            app.FrecOffKnobA.ValueChangingFcn = createCallbackFcn(app, @FrecOffKnobAValueChanging, true);
            app.FrecOffKnobA.Value = 100;
            app.LEDOff = uilamp(app.PanelOffR);
            app.FaseLabel = uilabel(app.PanelOffR);
            app.FaseLabel.Text = 'Fase [º]';
            app.FaseOffKnob = uiknob(app.PanelOffR, 'discrete');
            app.FaseOffKnob.Items = {'0', '45', '90', '180'};
            app.FaseOffKnob.ValueChangedFcn = createCallbackFcn(app, @FaseOffKnobValueChanged, true);
            app.FaseOffKnob.Value = '0';
            app.ActivarFrecOffResonanciaLabel = uilabel(app.PanelOffR);
            app.ActivarFrecOffResonanciaLabel.Text = 'Activar Frec. Off-Resonancia';
            app.SActivaFOff = uiswitch(app.PanelOffR, 'rocker');
            app.SActivaFOff.ValueChangedFcn = createCallbackFcn(app, @SActivaFOffValueChanged, true);
            app.FrecOffLabel = uilabel(app.PanelOffR);
            app.FrecOffLabel.Text = 'Frec. Off';
            app.FrecOffKnobD = uieditfield(app.PanelOffR, 'numeric');
            app.FrecOffKnobD.Limits = [0 100];
            app.FrecOffKnobD.ValueDisplayFormat = '%.2f';
            app.FrecOffKnobD.ValueChangedFcn = createCallbackFcn(app, @FrecOffKnobDValueChanged, true);
            app.FrecOffKnobD.Value = 100;
            app.PanelDer = uipanel(app.AppSSCPulsoRMN_GeneraS);
            app.GeneraDerivadaButton = uibutton(app.PanelDer, 'push');
            app.GeneraDerivadaButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraDerivadaButtonPushed, true);
            app.GeneraDerivadaButton.Text = 'Genera Derivada';
            app.RadiodeFiltroMedianaLabel = uilabel(app.PanelDer);
            app.RadiodeFiltroMedianaLabel.Text = 'Radio de Filtro Mediana ';
            app.rFMaEditField = uieditfield(app.PanelDer, 'numeric');
            app.rFMaEditField.Limits = [1 100];
            app.rFMaEditField.RoundFractionalValues = 'on';
            app.rFMaEditField.ValueDisplayFormat = '%.0f';
            app.rFMaEditField.Value = 2;
            app.ActivarFiltroMedianaLabel = uilabel(app.PanelDer);
            app.ActivarFiltroMedianaLabel.Text = {'Activar Filtro Mediana'; ''};
            app.SwFMa = uiswitch(app.PanelDer, 'slider');
            app.SwFMa.Value = 'On';
            app.PanelSIn = uipanel(app.AppSSCPulsoRMN_GeneraS);
            app.GeneraEntradaButton = uibutton(app.PanelSIn, 'push');
            app.GeneraEntradaButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraEntradaButtonPushed, true);
            app.GeneraEntradaButton.Text = 'Genera Entrada';
            app.EnvolventeDropDownLabel = uilabel(app.PanelSIn);
            app.EnvolventeDropDownLabel.Text = 'Envolvente';
            app.EnvolventeDropDown = uidropdown(app.PanelSIn);
            app.EnvolventeDropDown.Items = {'Sinc', 'Gaussiana Osc. 0º', 'Gaussiana Osc. 90º', 'Gaussiana', 'Chirp'};
            app.EnvolventeDropDown.ValueChangedFcn = createCallbackFcn(app, @EnvolventeDropDownValueChanged, true);
            app.EnvolventeDropDown.Value = 'Sinc';
            app.NrodepuntosEditFieldLabel = uilabel(app.PanelSIn);
            app.NrodepuntosEditFieldLabel.Text = 'Nro. de puntos:';
            app.NInEditField = uieditfield(app.PanelSIn, 'numeric');
            app.NInEditField.Limits = [0 Inf];
            app.NInEditField.RoundFractionalValues = 'on';
            app.NInEditField.ValueDisplayFormat = '%.0f';
            app.NInEditField.ValueChangedFcn = createCallbackFcn(app, @NInEditFieldValueChanged, true);
            app.NInEditField.Value = 10240;
            app.BorraSInButton = uibutton(app.PanelSIn, 'push');
            app.BorraSInButton.ButtonPushedFcn = createCallbackFcn(app, @BorraSInButtonPushed, true);
            app.BorraSInButton.Text = 'Borra Gráfico';
            app.SalirButton = uibutton(app.PanelSIn, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @AppSSCPulsoRMN_GeneraSCloseRequest, true);
            app.SalirButton.Text = 'Salir';
            app.PanelDetPul = uipanel(app.AppSSCPulsoRMN_GeneraS);
            app.ToleranciaDeteccinPulsoLabel = uilabel(app.PanelDetPul);
            app.ToleranciaDeteccinPulsoLabel.Text = {'Tolerancia Detección'; 'Pulso [%]'};
            app.TolDefPulKnobAIn = uiknob(app.PanelDetPul, 'continuous');
            app.TolDefPulKnobAIn.Limits = [0 10];
            app.TolDefPulKnobAIn.ValueChangedFcn = createCallbackFcn(app, @TolDefPulKnobAInValueChanged, true);
            app.TolDefPulKnobAIn.ValueChangingFcn = createCallbackFcn(app, @TolDefPulKnobAInValueChanging, true);
            app.TolDefPulKnobAIn.Value = 2;
            app.TolDefPulKnobDIn = uieditfield(app.PanelDetPul, 'numeric');
            app.TolDefPulKnobDIn.Limits = [0 20];
            app.TolDefPulKnobDIn.ValueDisplayFormat = '%.2f';
            app.TolDefPulKnobDIn.ValueChangedFcn = createCallbackFcn(app, @TolDefPulKnobDInValueChanged, true);
            app.TolDefPulKnobDIn.Value = 2;
            app.DetPulButton = uibutton(app.PanelDetPul, 'push');
            app.DetPulButton.ButtonPushedFcn = createCallbackFcn(app, @DetPulButtonPushed, true);
            app.DetPulButton.Text = 'Detecta Pulso';
            app.tiniLabel = uilabel(app.PanelDetPul);
            app.tiniLabel.Text = 't ini:';
            app.tiniDisp = uieditfield(app.PanelDetPul, 'numeric');
            app.tiniDisp.Limits = [0 Inf];
            app.tiniDisp.ValueDisplayFormat = '%.2f';
            app.tiniDisp.Editable = 'off';
            app.tfinEditFieldLabel = uilabel(app.PanelDetPul);
            app.tfinEditFieldLabel.Text = 't fin:';
            app.tfinDisp = uieditfield(app.PanelDetPul, 'numeric');
            app.tfinDisp.Limits = [0 Inf];
            app.tfinDisp.ValueDisplayFormat = '%.2f';
            app.tfinDisp.Editable = 'off';
            app.tfinDisp.Value = 1;
            app.PanelSSC = uipanel(app.AppSSCPulsoRMN_GeneraS);
            app.CalculaSSCButton = uibutton(app.PanelSSC, 'push');
            app.CalculaSSCButton.ButtonPushedFcn = createCallbackFcn(app, @CalculaSSCButtonPushed, true);
            app.CalculaSSCButton.Text = 'Calcula SSC';
            app.EntradaLabel = uilabel(app.PanelSSC);
            app.EntradaLabel.Text = 'Entrada';
            app.SWVerExtIn = uiswitch(app.PanelSSC, 'slider');
            app.SWVerExtIn.Items = {'NO', 'SI'};
            app.SWVerExtIn.Value = 'SI';
            app.DerivadaLabel = uilabel(app.PanelSSC);
            app.DerivadaLabel.Text = 'Derivada';
            app.SWVerExtDer = uiswitch(app.PanelSSC, 'slider');
            app.SWVerExtDer.Items = {'NO', 'SI'};
            app.SWVerExtDer.Value = 'SI';
            app.RadiodeDeteccindeExtremosLabel = uilabel(app.PanelSSC);
            app.RadiodeDeteccindeExtremosLabel.Text = {'Radio de Detección'; 'de Extremos'};
            app.rnExtEditField = uieditfield(app.PanelSSC, 'numeric');
            app.rnExtEditField.Limits = [0 Inf];
            app.rnExtEditField.RoundFractionalValues = 'on';
            app.rnExtEditField.ValueDisplayFormat = '%.0f';
            app.rnExtEditField.ValueChangedFcn = createCallbackFcn(app, @rnExtEditFieldValueChanged, true);
            app.rnExtEditField.Value = 5;
            app.IntegralLabel = uilabel(app.PanelSSC);
            app.IntegralLabel.Text = 'Integral';
            app.SWVerExtInt = uiswitch(app.PanelSSC, 'slider');
            app.SWVerExtInt.Items = {'NO', 'SI'};
            app.SWVerExtInt.Value = 'SI';
            app.TableSSC = uitable(app.PanelSSC);
            app.TableSSC.ColumnName = {'SSC'; 'Integral'; 'Señal'; 'Derivada'};
            app.TableSSC.RowName = {};
            app.HALTLabel = uilabel(app.PanelSSC);
            app.HALTLabel.Text = 'HALT';
            app.LEDSSC = uilamp(app.PanelSSC);
            app.CopyClipButton = uibutton(app.PanelSSC, 'push');
            app.CopyClipButton.ButtonPushedFcn = createCallbackFcn(app, @CopyClipButtonPushed, true);
            app.CopyClipButton.Text = 'Copiar al Clipboard';
            app.DecimalesLabel = uilabel(app.PanelSSC);
            app.DecimalesLabel.Text = 'Decimales';
            app.DeciEditField = uieditfield(app.PanelSSC, 'numeric');
            app.DeciEditField.Limits = [0 10];
            app.DeciEditField.ValueDisplayFormat = '%.0f';
            app.DeciEditField.Value = 4;
            app.VerExtremosLabel = uilabel(app.PanelSSC);
            app.VerExtremosLabel.Text = 'Ver Extremos';
            app.PanelInt = uipanel(app.AppSSCPulsoRMN_GeneraS);
            app.GeneraIntegralButton = uibutton(app.PanelInt, 'push');
            app.GeneraIntegralButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraIntegralButtonPushed, true);
            app.GeneraIntegralButton.Text = 'Genera Integral';
            app.PanelDICom = uipanel(app.AppSSCPulsoRMN_GeneraS);
            app.BorraSDerIntButton = uibutton(app.PanelDICom, 'push');
            app.BorraSDerIntButton.ButtonPushedFcn = createCallbackFcn(app, @BorraSDerIntButtonPushed, true);
            app.BorraSDerIntButton.Text = 'Borra Gráfico';
            app.EnviarButton = uibutton(app.AppSSCPulsoRMN_GeneraS, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            % Show the figure after all components are created
            app.AppSSCPulsoRMN_GeneraS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMNPulsoFormaLeeSSC_GN_DL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppSSCPulsoRMN_GeneraS)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppSSCPulsoRMN_GeneraS)
        end
    end
end