classdef RMNPulsoRealSSC_GN_DL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppSSCPulsoRMN_Real             matlab.ui.Figure
        PanelDeformaEsp                 matlab.ui.container.Panel
        VerEspectrosButton              matlab.ui.control.Button
        dimFFTEditField                 matlab.ui.control.NumericEditField
        dimFFTEditFieldLabel            matlab.ui.control.Label
        fampEditField                   matlab.ui.control.NumericEditField
        fampEditFieldLabel              matlab.ui.control.Label
        slrateEditField                 matlab.ui.control.NumericEditField
        slrateEditFieldLabel            matlab.ui.control.Label
        sigEditField                    matlab.ui.control.NumericEditField
        sigEditFieldLabel               matlab.ui.control.Label
        ParmetrosLabel                  matlab.ui.control.Label
        ffilEditField                   matlab.ui.control.NumericEditField
        ffilLabel                       matlab.ui.control.Label
        TipoDefEspKnob                  matlab.ui.control.DiscreteKnob
        DeformacinEspectralLabel        matlab.ui.control.Label
        PanelFiltroPulso                matlab.ui.container.Panel
        SWCorrOffSAmp                   matlab.ui.control.Switch
        CorregirOffSetAmpLabel          matlab.ui.control.Label
        nOffSEditField                  matlab.ui.control.NumericEditField
        NrodepuntosInicioFinLabel       matlab.ui.control.Label
        rFMeEditField                   matlab.ui.control.NumericEditField
        RadioFMediaLabel                matlab.ui.control.Label
        rFMaEditField                   matlab.ui.control.NumericEditField
        RadioFMedianaLabel              matlab.ui.control.Label
        SWAplicarFMedia                 matlab.ui.control.Switch
        AplicarFiltroMediaLabel         matlab.ui.control.Label
        SWAplicarFMediana               matlab.ui.control.Switch
        AplicarFiltroMedianaLabel       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        OffSetMaxEditField              matlab.ui.control.NumericEditField
        OffSetmxLabel                   matlab.ui.control.Label
        AtenMaxEditField                matlab.ui.control.NumericEditField
        AtenuacinmxLabel                matlab.ui.control.Label
        RuidoGAmpEditField              matlab.ui.control.NumericEditField
        AmpEditFieldLabel               matlab.ui.control.Label
        RuidoGFrecEditField             matlab.ui.control.NumericEditField
        FrecEditFieldLabel              matlab.ui.control.Label
        SWAplicarRuidoG                 matlab.ui.control.Switch
        AplicarRuidoglitchSwitchLabel   matlab.ui.control.Label
        SWAplicarRuidoB                 matlab.ui.control.Switch
        AplicarRuidoBlancoSwitchLabel   matlab.ui.control.Label
        RuidoBEditField                 matlab.ui.control.NumericEditField
        RuidoEditFieldLabel             matlab.ui.control.Label
        MuestreoPasoEditField           matlab.ui.control.NumericEditField
        PasoLabel                       matlab.ui.control.Label
        MuestreoFaseEditField           matlab.ui.control.NumericEditField
        FaseLabel                       matlab.ui.control.Label
        NuevoMuestreoLabel              matlab.ui.control.Label
        NInEditField                    matlab.ui.control.NumericEditField
        NrodepuntosLabel                matlab.ui.control.Label
        RuidoBKnob                      matlab.ui.control.Knob
        PanelDICom                      matlab.ui.container.Panel
        BorraSDerIntButton              matlab.ui.control.Button
        PanelInt                        matlab.ui.container.Panel
        GeneraIntegralButton            matlab.ui.control.Button
        PanelSSC                        matlab.ui.container.Panel
        GrabaOndaSwitch                 matlab.ui.control.Switch
        GrabaOndaSwitchLabel            matlab.ui.control.Label
        NCActEditField                  matlab.ui.control.NumericEditField
        IteracinactualEditFieldLabel    matlab.ui.control.Label
        NCMaxEditField                  matlab.ui.control.NumericEditField
        NrodeiteracionesEditFieldLabel  matlab.ui.control.Label
        EjecutaCicloButton              matlab.ui.control.Button
        DetieneCicloButton              matlab.ui.control.Button
        VerExtremosLabel                matlab.ui.control.Label
        DeciEditField                   matlab.ui.control.NumericEditField
        DecimalesLabel                  matlab.ui.control.Label
        CopyClipButton                  matlab.ui.control.Button
        LEDSSC                          matlab.ui.control.Lamp
        HALTLabel                       matlab.ui.control.Label
        TableSSC                        matlab.ui.control.Table
        SWVerExtInt                     matlab.ui.control.Switch
        IntegralLabel                   matlab.ui.control.Label
        rnExtEditField                  matlab.ui.control.NumericEditField
        RadiodeDeteccindeExtremosLabel  matlab.ui.control.Label
        SWVerExtDer                     matlab.ui.control.Switch
        DerivadaLabel                   matlab.ui.control.Label
        SWVerExtIn                      matlab.ui.control.Switch
        EntradaLabel                    matlab.ui.control.Label
        CalculaSSCButton                matlab.ui.control.Button
        PanelDetPul                     matlab.ui.container.Panel
        tfinDisp                        matlab.ui.control.NumericEditField
        tfinEditFieldLabel              matlab.ui.control.Label
        tiniDisp                        matlab.ui.control.NumericEditField
        tiniLabel                       matlab.ui.control.Label
        DetPulButton                    matlab.ui.control.Button
        TolDefPulKnobDIn                matlab.ui.control.NumericEditField
        TolDefPulKnobAIn                matlab.ui.control.Knob
        ToleranciaDeteccinPulsoLabel    matlab.ui.control.Label
        PanelSIn                        matlab.ui.container.Panel
        SWAplicarRuido                  matlab.ui.control.Switch
        AplicarRuidoSwitchLabel         matlab.ui.control.Label
        ActualizarEntradaButton         matlab.ui.control.Button
        VerEntradaButton                matlab.ui.control.Button
        SalirButton                     matlab.ui.control.Button
        BorraSInButton                  matlab.ui.control.Button
        PanelDer                        matlab.ui.container.Panel
        SwFMa                           matlab.ui.control.Switch
        ActivarFiltroMedianaLabel       matlab.ui.control.Label
        rFMaDerEditField                matlab.ui.control.NumericEditField
        RadiodeFiltroMedianaLabel       matlab.ui.control.Label
        GeneraDerivadaButton            matlab.ui.control.Button
        UIAxesSDerInt                   matlab.ui.control.UIAxes
        UIAxesSIn                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)              
        %%%%% Señal de entrada:
        FNIn % Nro. de puntos de la señal completa (Full)
        FdtIn % Paso de tiempo para la señal completa
        FtIn % Valores de tiempo de la señal completa
        NIn % Nro. de puntos de la señal o pulso
        dtIn % Paso de tiempo del pulso
        tIn % Valores de tiempo del pulso

        FSignalIn % Señal completa  
        FFSignalIn % Señal completa Filtrada
        SignalIn % Señal
        DerSignalIn % Derivada
        IntSignalIn % Integral
        FAux % Función auxiliar
        
        %%%%% Deformación Espectral
        dimFFT % Cantidad de puntos con relleno de ceros para la FFT
        tipoDE % Tipo de deformación espectral
        ffil % Frecuencia característica del filtro
        famp % Factor de amplitud del filtro GaussBurn
        sig % Ancho en frecuencia del filtro GaussBurn
        slrate % Pendiente o flanco del perfil del filtro Pasa Bajos
        
        %%%%% Ruido
        MuestreoPaso % Paso del nuevo muestreo
        MuestreoFase % Fase del nuevo muestreo
        Rjitter % Desplazamiento aleatorio del inicio del muestreo (Ruido jitter)
        ValAtenMax % Valor [%] de máxima atenuación de la amplitud
        ValOffSetMax % Valor [%] del máximo offset de la señal
        ValRB % Valor [%] ruido blanco
        ValRG % Valor [%] de amplitud máxima de ruido glitch
        FrecRG % Frecuencia der ruido glitch
        Rglitch % Señal de ruido glitch
        rnMa % Radio del Filtro de Mediana
        rnMe % Radio del Filtro de Medio
        nOffS % Nro. de puntos al inicio/final de la señal para determinar el OffSet
        ValOffS % Valor del offset de la señal
        
        %%%%% Variables para el File SSC
        ArchC % Manejador del File
        NomArchC % Nombre del file
        msjArchC % Mensaje de estado del File
        NCicloMax % Nro. máximo de ciclos
        FlagSalirCiclo % Bandera para detención de ciclo
        %%%%% Variables para el File de la Onda
        FlagGrabaOnda % Bandera para grabar o no la Onda
        ArchO % Manejador del File
        NomArchO % Nombre del file
        msjArchO % Mensaje de estado del File
 
        
        %%%%% Parámetros:
        tolDefPulIn % Tolerancia para definir el inicio y fin del pulso
        niniPIn % Posición inicial del pulso
        nfinPIn % Posición final del pulso
        rnext % Cantidad de elementos por punto para buscar los extremos
        % Extremos de la Señal o Pulso:
        posextIn % Posición en la Señal o Pulso
        posextDerIn % Posición en la Derivada
        posextIntIn % Posición en la Integral
        DatosSSC % Tabla de valores de los parámetros SSC
        GrillaSSC % Arreglo con los valores SSC
    end
    
    properties (Access = public)
        mapp % Aplicación principal con la señal de entrada
        
        Mt,Dt,Ma,Da % Parámetros SSC
        Ma1,Da2,Da3,Mt1,Dt2,Dt3 % Parámetros SSC extendidos
        
        frecSig % Valores de frecuencia del espectro de la señal
        FFTSig % Transformada de Fourier de la señal o pulso
        FFTFil % Forma espectral del filtro de deformación
        FFTDSig % Transformada de Fourier de la señal deformada
    end
    
    methods (Access = private)

        function ActualizaEntrada(app)
            if ~isempty(app.mapp.FSignalIn)
                app.FSignalIn = [];
                app.FtIn = [];
                app.Rjitter = 0;
                if strcmp(app.SWAplicarRuido.Value,'SI')
                    if app.MuestreoFase ~= 0
                        app.Rjitter = round(app.MuestreoFase * rand(1,1));
                    end                
                end
                app.FSignalIn = downsample(app.mapp.FSignalIn, app.MuestreoPaso, app.Rjitter);
                app.FtIn = downsample(app.mapp.FtIn, app.MuestreoPaso, app.Rjitter);
                app.FNIn = size(app.FtIn,2);
                % disp(app.FNIn)
                app.NInEditField.Value = app.FNIn;
                app.FdtIn = (app.FtIn(app.FNIn)-app.FtIn(1))/(app.FNIn-1);
                if ~strcmp(app.tipoDE,'NO')
                    AplicaDefEspectro(app); 
                end
                if strcmp(app.SWAplicarRuido.Value,'SI')
                    if app.ValAtenMax ~= 0
                        app.FSignalIn = app.FSignalIn * (1 - rand(1,1) * app.ValAtenMax);
                    end
                    ValMA_FS = max(abs(app.FSignalIn));
                    if app.ValOffSetMax ~= 0
                        app.FSignalIn = app.FSignalIn + app.ValOffSetMax * (2 * rand(1,1) - 1);
                    end
                    if strcmp(app.SWAplicarRuidoB.Value,'SI')
                        %app.FSignalIn = app.FSignalIn + app.ValRB * (2 * rand(1,app.FNIn) - 1);
                        %app.FSignalIn = app.FSignalIn + app.ValRB * randn(1,app.FNIn);                       
                        app.FSignalIn = app.FSignalIn + ValMA_FS * app.ValRB * randn(1,app.FNIn);
                    end
                    if strcmp(app.SWAplicarRuidoG.Value,'SI')
                        app.Rglitch = [];
                        app.Rglitch(1:app.FrecRG:app.FNIn) = 1;
                        app.Rglitch((end+1):app.FNIn) = 0;
                        app.Rglitch = app.Rglitch .* (app.ValRG * (2*((2*rand(1,app.FNIn)-1) > 0) - 1));
                        app.FSignalIn = app.FSignalIn + app.Rglitch;
                    end
                end
             end                       
        end   
        
        function AplicaDefEspectro(app)
            app.FFTSig = fft(app.FSignalIn,app.dimFFT);
            app.FFTSig = fftshift(app.FFTSig);
            %size(app.FSignalIn)
            %size(app.FFTSig)

            Nfft = floor(size(app.FFTSig,2)/2);
            fs = 1/app.FdtIn;
            if mod(size(app.FFTSig,2),2)
            	app.frecSig = 0.5*fs*(-Nfft:Nfft)/Nfft;
            else
            	app.frecSig = 0.5*fs*(-Nfft:(Nfft-1))/Nfft;
            end
            
            switch lower(app.tipoDE)
                case 'gaussburn'
                    app.FFTFil = (exp(-(app.frecSig-app.ffil).^2/(2*app.sig^2)) +...
                        exp(-(app.frecSig+app.ffil).^2/(2*app.sig^2)))/sqrt(2*pi*app.sig^2);
                    app.FFTFil = 1 - app.famp*app.FFTFil/max(abs(app.FFTFil));
                case 'pb'
                    app.FFTFil = tanh(app.slrate*(app.frecSig+app.ffil))/2 -...
                        tanh(app.slrate*(app.frecSig-app.ffil))/2;
            end
            
            FFTamp = abs(app.FFTSig) .* app.FFTFil;
            FFTfase = angle(app.FFTSig);
            app.FFTDSig = FFTamp .* (cos(FFTfase) + 1i * sin(FFTfase));
            
            FDSignalIn = real(ifft(fftshift(app.FFTDSig)));
            app.FSignalIn = FDSignalIn(1:app.FNIn);
        end
        
        function DefinePulso(app)
            AplicaFiltrosCorrOffSAmp(app);
            DetectaPulso(app);
            app.tiniDisp.Value = app.FtIn(app.niniPIn);
            app.tfinDisp.Value = app.FtIn(app.nfinPIn);
            app.NIn = app.nfinPIn - app.niniPIn + 1;
            
            app.SignalIn = [];
            app.SignalIn = app.FFSignalIn(app.niniPIn:app.nfinPIn);
            %%%%%% Normaliza amplitud
            app.SignalIn = app.SignalIn/max(abs(app.SignalIn));
            %%%%%%
            
            app.tIn = [];
            %%%%%% Normaliza tiempo 
            app.dtIn = 1/(app.NIn-1);
            app.tIn = (0:(app.NIn-1))*app.dtIn;           
            %%%%%%                 
        end
        
        function AplicaFiltrosCorrOffSAmp(app)
            app.FFSignalIn = app.FSignalIn;
            if strcmp(app.SWAplicarFMediana.Value,'SI')
                app.FFSignalIn = Filtro0_Mediana(app.FFSignalIn, app.rnMa);
            end
            if strcmp(app.SWAplicarFMedia.Value,'SI')
                app.FFSignalIn = Filtro0_Media(app.FFSignalIn, app.rnMe);
            end
            if strcmp(app.SWCorrOffSAmp.Value,'SI')
                if app.FNIn >= (2*app.nOffS+1)
                    app.ValOffS = sum(app.FFSignalIn(1:app.nOffS));
                    app.ValOffS = app.ValOffS + sum(app.FFSignalIn(app.FNIn:-1:(app.FNIn-app.nOffS)));
                    app.ValOffS = app.ValOffS /(2*app.nOffS+1);
                    % disp(app.ValOffS)
                    app.FFSignalIn = app.FFSignalIn - app.ValOffS;
                end
                app.FFSignalIn = app.FFSignalIn / max(abs(app.FFSignalIn));
            end
        end
        
        function DetectaExtremos(app)
            if ~isempty(app.SignalIn)
                app.posextIn = [];
                app.posextIn = Calcula_Extremos(app.SignalIn,app.rnext);
            end
            if ~isempty(app.DerSignalIn)
                app.posextDerIn = [];
                app.posextDerIn = Calcula_Extremos(app.DerSignalIn,app.rnext);
            end
            if ~isempty(app.SignalIn)
                app.posextIntIn = [];
                app.posextIntIn = Calcula_Extremos(app.IntSignalIn,app.rnext);
            end
        end
        
        function DetectaPulso(app)
            [app.niniPIn, app.nfinPIn] = Extrae_Pulso(app.FFSignalIn,app.tolDefPulIn/100);
        end
        
        function GeneraDerivada(app)
            app.DerSignalIn = [];
            CalcDerivada(app);                      
            if strcmp(app.SwFMa.Value,'On')
                app.DerSignalIn = Filtro0_Mediana(app.DerSignalIn,app.rFMaDerEditField.Value);
            end
            app.DerSignalIn = app.DerSignalIn/max(abs(app.DerSignalIn));
        end
        
        function GeneraIntegral(app)
            app.IntSignalIn = [];
            CalcIntegral(app);
            app.IntSignalIn = app.IntSignalIn/max(abs(app.IntSignalIn));           
        end
        
        function CalcDerivada(app)
            %%%%%% Método 1:
            app.FAux = [];
            app.FAux = app.SignalIn(2:app.NIn);
            app.FAux(app.NIn) = 0;
            app.DerSignalIn = app.FAux-app.SignalIn;
            %%%%%%
            %%%%%% Método 2
            % for n = 1:(app.NIn-1)
            %     app.DerSignalIn(n) = (app.SignalIn(n+1)-app.SignalIn(n));
            % end
            % app.DerSignalIn(app.NIn) = -app.SignalIn(app.NIn);
            %%%%%%
        end

        function CalcIntegral(app)
            %%%%%% Método 1:
            app.IntSignalIn = cumsum(app.SignalIn);    
            %%%%%%
            %%%%%% Método 2:
            % ValInt = 0;
            % for n = 1:app.NIn
            %     ValInt = ValInt + app.SignalIn(n);
            %     app.IntSignalIn(n) = ValInt;
            % end           
            %%%%%%
        end
        
        function GeneraSSC(app)
            DetectaExtremos(app);
            if ~isempty(app.posextIn)
                nfun = 2;
                Calc_SSC(app,app.tIn,app.SignalIn,nfun,app.posextIn);
                app.DatosSSC.ValS = [app.Mt(nfun);app.Dt(nfun);app.Ma(nfun);app.Da(nfun);...
                    app.Ma1(nfun);app.Da2(nfun);app.Da3(nfun);app.Mt1(nfun);app.Dt2(nfun);app.Dt3(nfun)];
            end
            if ~isempty(app.posextDerIn)
                nfun = 3;
                Calc_SSC(app,app.tIn,app.DerSignalIn,nfun,app.posextDerIn);
                app.DatosSSC.ValD = [app.Mt(nfun);app.Dt(nfun);app.Ma(nfun);app.Da(nfun);...
                    app.Ma1(nfun);app.Da2(nfun);app.Da3(nfun);app.Mt1(nfun);app.Dt2(nfun);app.Dt3(nfun)];
            end
            if ~isempty(app.posextIntIn)
                nfun = 1;
                Calc_SSC(app,app.tIn,app.IntSignalIn,nfun,app.posextIntIn);
                app.DatosSSC.ValI = [app.Mt(nfun);app.Dt(nfun);app.Ma(nfun);app.Da(nfun);...
                    app.Ma1(nfun);app.Da2(nfun);app.Da3(nfun);app.Mt1(nfun);app.Dt2(nfun);app.Dt3(nfun)];                    
            end
            app.GrillaSSC = [app.DatosSSC.ValI,app.DatosSSC.ValS,app.DatosSSC.ValD];
        end        

        function cod = GrabaArchivoCiclo(app)
            app.EjecutaCicloButton.Enable = 'off';
            app.DetieneCicloButton.Enable = 'on';
            cod = 0;
            app.LEDSSC.Color = [1,0,0];
            %%%%%%%%%%%%%%%
            app.FlagGrabaOnda = 0;
            if strcmp(app.SWVerExtDer.Value,'SI')
                app.FlagGrabaOnda = 1;
            end
            %%%%%%%%%%%%%%%        
            
            %%%% Archivo de ejecución de Ciclo-SSC
            CTexto = 'Ciclo-SSC';
            dia = fix(clock);
            app.NomArchC = strcat(CTexto, num2str([dia(1) dia(2) dia(3)], '_%d-%d-%d_'),...
            	num2str([dia(4) dia(5) dia(6)], '(%d.%d.%d)'));
            app.ArchC = 0;
            [app.ArchC,app.msjArchC] = fopen([app.NomArchC,'.txt'],'wt');
            if app.ArchC == -1 
            	cod = -1;
            	return
            end
            %%%%%%%%%%%%%%%
            if app.FlagGrabaOnda
                %%%% Archivo de Onda
                OTexto = 'Ciclo-Onda';
                dia = fix(clock);
                app.NomArchO = strcat(OTexto, num2str([dia(1) dia(2) dia(3)], '_%d-%d-%d_'),...
                	num2str([dia(4) dia(5) dia(6)], '(%d.%d.%d)'));
                app.ArchO = 0;
                [app.ArchO,app.msjArchO] = fopen([app.NomArchO,'.txt'],'wt');
                if app.ArchO == -1 
                	cod = -1;
                	return
                end                
            end
            %%%%%%%%%%%%%%%
            
            fprintf(app.ArchC,'**** Parámetros SSC ****\n');
            fprintf(app.ArchC,'Tipo de Pulso: %s - Activar Frec. Off-Resonancia: %s - Frec. Off = %6.2f - Fase [º] = %6.2f | Def. Espectro: %s',...
                app.mapp.tipoEnv,app.mapp.ActivaOff,app.mapp.FrecOff,app.mapp.FaseOff,app.tipoDE);
            switch lower(app.tipoDE)
                case 'gaussburn'
                    fprintf(app.ArchC,' [dimFFT = %6.0d, ffil = %5.2f, famp = %4.2f, sig = %5.2f]\n',...
                        app.dimFFT,app.ffil,app.famp,app.sig);
                case 'pb'
                    fprintf(app.ArchC,' [dimFFT = %6.0d, ffil = %5.2f, slrate = %5.2f]\n',...
                        app.dimFFT,app.ffil,app.slrate);                    
                otherwise
                    fprintf(app.ArchC,'\n');
            end            
            fprintf(app.ArchC,'Aplicar Ruido: %s\n',app.SWAplicarRuido.Value);
            fprintf(app.ArchC,'Muestreo: Paso = %4.0d - Fase = %4.0d - Nro. de puntos = %5.0d\n',app.MuestreoPaso,app.MuestreoFase,app.FNIn);
            fprintf(app.ArchC,'Atenuación máx. [%%] = %3.0d - OffSet máx. [%%] = %3.0d\n',app.ValAtenMax*100,app.ValOffSetMax*100);
            fprintf(app.ArchC,'Aplicar Ruido Blanco: %s - Ruido [%%] = %3.0d\n',app.SWAplicarRuidoB.Value,app.ValRB*100);
            fprintf(app.ArchC,'Aplicar Ruido glitch: %s - Amp. [%%] = %3.0d - Frec. = %4.0d\n',app.SWAplicarRuidoG.Value,app.ValRG*100,app.FrecRG);
            fprintf(app.ArchC,'Aplicar Filtro Mediana: %s - Radio F. Mediana = %3.0d\n',app.SWAplicarFMediana.Value,app.rnMa);
            fprintf(app.ArchC,'Aplicar Filtro Media: %s - Radio F. Media = %3.0d\n',app.SWAplicarFMediana.Value,app.rnMe);
            fprintf(app.ArchC,'Corregir OffSet/Amp.: %s - Nro. de puntos Inicio/Fin = %4.0d\n',app.SWCorrOffSAmp.Value,app.nOffS);
            fprintf(app.ArchC,'Detecta Pulso: Tolerancia Detección Pulso [%%] = %2.0d\n',app.tolDefPulIn);
            fprintf(app.ArchC,'Genera Derivada - Activar Filtro Mediana: %s - Radio de Filtro Mediana = %2.0d\n',app.SwFMa.Value,app.rFMaDerEditField.Value);
            fprintf(app.ArchC,'Calcula SSC - Radio de Detección de Extremos = %2.0d\n',app.rnext);
            
            fprintf(app.ArchC,'\nDatos Columnas: [Integral, Señal, Derivada]\n');
            fprintf(app.ArchC,'Datos Filas (SSC): [Mt; Dt; Ma; Da; Ma1; Da2; Da3; Mt1; Dt2; Dt3]\n');
            
            fprintf(app.ArchC,'\nSSC - Pulso Original:\n');
            strSCC = num2str(app.mapp.GrillaSSC,'%8.4f,');
            Nstr = size(strSCC,1);
            strSCC(:,end) = ';';
            strSCC(Nstr,end) = ' ';
            fprintf(app.ArchC,'[');
            for n=1:Nstr
                fprintf(app.ArchC,' %s',strSCC(n,:));
            end
            fprintf(app.ArchC,']\n');

            fprintf(app.ArchC,'\nSSC - Secuencia de Pulsos - Nro. de iteraciones = %5.0d:\n',app.NCicloMax);
            
            app.FlagSalirCiclo = 0;
            for NCiclo = 1:app.NCicloMax
                app.NCActEditField.Value = NCiclo;
                
                ActualizaEntrada(app);
                DefinePulso(app);
                GeneraDerivada(app);
                GeneraIntegral(app);
                GeneraSSC(app);
        
                strSCC = num2str(app.GrillaSSC,'%8.4f,');
                Nstr = size(strSCC,1);
                strSCC(:,end) = ';';
                strSCC(Nstr,end) = ' ';
                fprintf(app.ArchC,'[');
                for n=1:Nstr
                    fprintf(app.ArchC,' %s',strSCC(n,:));
                end
                fprintf(app.ArchC,']\n');
                
                %%%%%%%%%%%%%%%%
                if app.FlagGrabaOnda
                    fprintf(app.ArchO,'[%s]\n',num2str(app.FSignalIn));
                    fprintf(app.ArchO,'[%s]\n',num2str(app.FtIn));
                end
                %%%%%%%%%%%%%%%%
                
                if app.FlagSalirCiclo
                    break
                end
                
            end
            
            %%%%%%%%%%%%%%%
            fclose(app.ArchC);
            if app.FlagGrabaOnda
                fclose(app.ArchO);
            end
            %%%%%%%%%%%%%%%
            app.LEDSSC.Color = [0.4,0.1,0.2];
            app.DetieneCicloButton.Enable = 'off';
            app.EjecutaCicloButton.Enable = 'on';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Oapp)
            app.mapp = Oapp;
            
            app.NIn = app.FNIn;
            app.dtIn = app.FdtIn;
            app.tIn = app.FtIn;
            app.SignalIn = [];
            app.DerSignalIn = [];
            app.IntSignalIn = [];
            app.FFSignalIn = [];
            app.FFTSig = [];
            app.FFTFil = [];
            app.FFTDSig = [];
            app.frecSig = [];
            
            app.dimFFT = app.dimFFTEditField.Value;
            app.tipoDE = app.TipoDefEspKnob.Value;
            app.ffil = app.ffilEditField.Value;
            app.famp = app.fampEditField.Value;
            app.sig = app.sigEditField.Value;
            app.slrate = app.slrateEditField.Value;
                
            % app.MuestreoPaso = 10;
            % app.MuestreoPasoEditField.Value = app.MuestreoPaso;
            app.MuestreoPaso = app.MuestreoPasoEditField.Value;
            % app.MuestreoFase = 0;
            % app.MuestreoFaseEditField.Value = app.MuestreoFase;
            app.MuestreoFase = app.MuestreoFaseEditField.Value;
            app.Rjitter = app.MuestreoFase;
            % app.ValAtenMax = 0.25;
            % app.AtenMaxEditField.Value = app.ValAtenMax * 100;
            app.ValAtenMax = app.AtenMaxEditField.Value / 100;
            % app.ValOffSetMax = 0.05;
            % app.OffSetMaxEditField.Value = app.ValOffSetMax * 100;
            app.ValOffSetMax = app.OffSetMaxEditField.Value / 100;
            % app.ValRB = 0.1;
            % app.RuidoBEditField.Value = app.ValRB * 100;
            app.ValRB = app.RuidoBEditField.Value / 100;
            % app.RuidoBKnob.Value = app.ValRB * 100;
            app.RuidoBKnob.Value = app.RuidoBEditField.Value;
            % app.ValRG = 0.25;
            % app.RuidoGAmpEditField.Value = app.ValRG * 100;
            app.ValRG = app.RuidoGAmpEditField.Value / 100;
            % app.FrecRG = 100;
            % app.RuidoGFrecEditField.Value = app.FrecRG;
            app.FrecRG = app.RuidoGFrecEditField.Value;
            app.Rglitch = [];
            % app.rnMa = 5;
            % app.rFMaEditField.Value = app.rnMa;
            app.rnMa = app.rFMaEditField.Value;
            % app.rnMe = 5;
            % app.rFMeEditField.Value = app.rnMe;
            app.rnMe = app.rFMeEditField.Value;
            app.nOffS = app.nOffSEditField.Value;         
            app.ValOffS = 0;            
            app.NCicloMax = app.NCMaxEditField.Value;
                                    
            % app.tolDefPulIn = 10; 
            % app.TolDefPulKnobAIn.Value = app.tolDefPulIn;
            % app.TolDefPulKnobDIn.Value = app.tolDefPulIn;
            app.tolDefPulIn = app.TolDefPulKnobDIn.Value;
            app.TolDefPulKnobAIn.Value = app.TolDefPulKnobDIn.Value;
            
            app.niniPIn = 0;
            app.nfinPIn = app.NIn;
            % app.rnext = 5;
            app.rnext = app.rnExtEditField.Value;
            app.posextIn = [];
            app.posextDerIn = [];
            app.posextIntIn = [];
            app.Mt = []; 
            app.Dt = []; 
            app.Ma = []; 
            app.Da = []; 
            app.Ma1 = []; 
            app.Da2 = []; 
            app.Da3 = [];
            app.Mt1 = []; 
            app.Dt2 = []; 
            app.Dt3 = [];
            
            app.DatosSSC = table('Size',[10,4],'VariableTypes',{'string','double','double','double'},...
                'VariableNames',{'NombreSSC','ValI','ValS','ValD'});
            app.DatosSSC.NombreSSC = ["Mt";"Dt";"Ma";"Da";"Ma1";"Da2";"Da3";"Mt1";"Dt2";"Dt3"];
            app.TableSSC.Data = app.DatosSSC;
            app.GrillaSSC = [app.DatosSSC.ValI,app.DatosSSC.ValS,app.DatosSSC.ValD];
            
            ActualizaEntrada(app);
            plot(app.UIAxesSIn,app.FtIn,app.FSignalIn,'r','LineWidth',1);
            xlim(app.UIAxesSIn,[0,1]);
            ylim(app.UIAxesSIn,[-1,1]);
            
            app.mapp.sapp2 = [];
        end

        % Button pushed function: GeneraDerivadaButton
        function GeneraDerivadaButtonPushed(app, event)
            if ~isempty(app.SignalIn)
                GeneraDerivada(app);
                plot(app.UIAxesSDerInt,app.tIn,app.DerSignalIn,'b','LineWidth',1);
                xlim(app.UIAxesSDerInt,[0,1]);
                ylim(app.UIAxesSDerInt,[-1,1]);
            end
        end

        % Button pushed function: GeneraIntegralButton
        function GeneraIntegralButtonPushed(app, event)
            if ~isempty(app.SignalIn)
                GeneraIntegral(app);
                plot(app.UIAxesSDerInt,app.tIn,app.IntSignalIn,'g','LineWidth',1);
                xlim(app.UIAxesSDerInt,[0,1]);
                ylim(app.UIAxesSDerInt,[-1,1]);
            end
        end

        % Button pushed function: BorraSDerIntButton
        function BorraSDerIntButtonPushed(app, event)
            cla(app.UIAxesSDerInt);
        end

        % Value changing function: TolDefPulKnobAIn
        function TolDefPulKnobAInValueChanging(app, event)
            app.TolDefPulKnobDIn.Value = event.Value;            
        end

        % Value changed function: TolDefPulKnobAIn
        function TolDefPulKnobAInValueChanged(app, event)
            app.tolDefPulIn = app.TolDefPulKnobAIn.Value;
            app.TolDefPulKnobDIn.Value = app.tolDefPulIn;
        end

        % Value changed function: TolDefPulKnobDIn
        function TolDefPulKnobDInValueChanged(app, event)
            app.tolDefPulIn = app.TolDefPulKnobDIn.Value;
            app.TolDefPulKnobAIn.Value = app.tolDefPulIn;
        end

        % Button pushed function: DetPulButton
        function DetPulButtonPushed(app, event)
            if ~isempty(app.FSignalIn)
                DefinePulso(app);
                plot(app.UIAxesSIn,app.tIn,app.SignalIn,'k','LineWidth',1);
                xlim(app.UIAxesSIn,[0,1]);
                ylim(app.UIAxesSIn,[-1,1]);
            end            
        end

        % Button pushed function: BorraSInButton
        function BorraSInButtonPushed(app, event)
            cla(app.UIAxesSIn);
        end

        % Value changed function: rnExtEditField
        function rnExtEditFieldValueChanged(app, event)
            app.rnext = app.rnExtEditField.Value;
        end

        % Button pushed function: CalculaSSCButton
        function CalculaSSCButtonPushed(app, event)
            app.LEDSSC.Color = [1,0,0];
            GeneraSSC(app);
            if ~isempty(app.posextIn) && strcmp(app.SWVerExtIn.Value,'SI')
                plot(app.UIAxesSIn,app.tIn(app.posextIn),app.SignalIn(app.posextIn),...
                    'o','LineWidth',2,'MarkerEdgeColor','m','MarkerSize',8);
                xlim(app.UIAxesSIn,[0,1]);
                ylim(app.UIAxesSIn,[-1,1]);
            end
            if ~isempty(app.posextDerIn) && strcmp(app.SWVerExtDer.Value,'SI')
                plot(app.UIAxesSDerInt,app.tIn(app.posextDerIn),app.DerSignalIn(app.posextDerIn),...
                    'o','LineWidth',2,'MarkerEdgeColor','c','MarkerSize',8);
                xlim(app.UIAxesSDerInt,[0,1]);
                ylim(app.UIAxesSDerInt,[-1,1]);
            end
            if ~isempty(app.posextIntIn) && strcmp(app.SWVerExtInt.Value,'SI')
                plot(app.UIAxesSDerInt,app.tIn(app.posextIntIn),app.IntSignalIn(app.posextIntIn),...
                    'o','LineWidth',2,'MarkerEdgeColor','y','MarkerSize',8);
                xlim(app.UIAxesSDerInt,[0,1]);
                ylim(app.UIAxesSDerInt,[-1,1]);
            end
            app.TableSSC.Data = app.DatosSSC;
            app.LEDSSC.Color = [0.4,0.1,0.2];
        end

        % Button pushed function: CopyClipButton
        function CopyClipButtonPushed(app, event)
            clipboard('copy', round(app.GrillaSSC,app.DeciEditField.Value));
        end

        % Button pushed function: VerEntradaButton
        function VerEntradaButtonPushed(app, event)
            plot(app.UIAxesSIn,app.FtIn,app.FSignalIn,'r','LineWidth',1);
            xlim(app.UIAxesSIn,[0,1]);
            ylim(app.UIAxesSIn,[-1,1]);
        end

        % Callback function: AppSSCPulsoRMN_Real, SalirButton
        function AppSSCPulsoRMN_RealCloseRequest(app, event)
            app.mapp.EnviarButton.Enable = 'on';
            if ~isempty(app.mapp.sapp2)
                delete(app.mapp.sapp2);
                app.mapp.sapp2 = [];
            end
            app.mapp.sapp = [];            
            delete(app);
        end

        % Button pushed function: ActualizarEntradaButton
        function ActualizarEntradaButtonPushed(app, event)
            ActualizaEntrada(app);
        end

        % Value changing function: RuidoBKnob
        function RuidoBKnobValueChanging(app, event)
            app.RuidoBEditField.Value = event.Value;            
        end

        % Value changed function: RuidoBKnob
        function RuidoBKnobValueChanged(app, event)
            app.ValRB = app.RuidoBKnob.Value / 100;
            app.RuidoBEditField.Value = app.RuidoBKnob.Value;
        end

        % Value changed function: RuidoBEditField
        function RuidoBEditFieldValueChanged(app, event)
            app.ValRB = app.RuidoBEditField.Value / 100;
            app.RuidoBKnob.Value = app.RuidoBEditField.Value;
        end

        % Value changed function: AtenMaxEditField
        function AtenMaxEditFieldValueChanged(app, event)
            app.ValAtenMax = app.AtenMaxEditField.Value / 100;            
        end

        % Value changed function: MuestreoPasoEditField
        function MuestreoPasoEditFieldValueChanged(app, event)
            app.MuestreoPaso = app.MuestreoPasoEditField.Value; 
            if app.MuestreoFaseEditField.Value >= app.MuestreoPaso
                app.MuestreoFaseEditField.Value = app.MuestreoPaso - 1;
                app.MuestreoFase = app.MuestreoFaseEditField.Value;            
            end
        end

        % Value changed function: MuestreoFaseEditField
        function MuestreoFaseEditFieldValueChanged(app, event)
            if app.MuestreoFaseEditField.Value >= app.MuestreoPaso
                app.MuestreoFaseEditField.Value = app.MuestreoPaso - 1;
            end
            app.MuestreoFase = app.MuestreoFaseEditField.Value;
        end

        % Value changed function: RuidoGAmpEditField
        function RuidoGAmpEditFieldValueChanged(app, event)
            app.ValRG = app.RuidoGAmpEditField.Value / 100;           
        end

        % Value changed function: RuidoGFrecEditField
        function RuidoGFrecEditFieldValueChanged(app, event)
            app.FrecRG = app.RuidoGFrecEditField.Value;            
        end

        % Value changed function: OffSetMaxEditField
        function OffSetMaxEditFieldValueChanged(app, event)
            app.ValOffSetMax = app.OffSetMaxEditField.Value / 100;            
        end

        % Value changed function: rFMaEditField
        function rFMaEditFieldValueChanged(app, event)
            app.rnMa = app.rFMaEditField.Value;
        end

        % Value changed function: rFMeEditField
        function rFMeEditFieldValueChanged(app, event)
            app.rnMe = app.rFMeEditField.Value;            
        end

        % Value changed function: nOffSEditField
        function nOffSEditFieldValueChanged(app, event)
            app.nOffS = app.nOffSEditField.Value;           
        end

        % Button pushed function: EjecutaCicloButton
        function EjecutaCicloButtonPushed(app, event)
            GrabaArchivoCiclo(app);
        end

        % Value changed function: NCMaxEditField
        function NCMaxEditFieldValueChanged(app, event)
            app.NCicloMax = app.NCMaxEditField.Value;
        end

        % Button pushed function: DetieneCicloButton
        function DetieneCicloButtonPushed(app, event)
            app.FlagSalirCiclo = 1;
        end

        % Value changed function: TipoDefEspKnob
        function TipoDefEspKnobValueChanged(app, event)
            app.tipoDE = app.TipoDefEspKnob.Value;            
        end

        % Value changed function: dimFFTEditField
        function dimFFTEditFieldValueChanged(app, event)
            app.dimFFT = app.dimFFTEditField.Value;            
        end

        % Value changed function: ffilEditField
        function ffilEditFieldValueChanged(app, event)
            app.ffil = app.ffilEditField.Value;            
        end

        % Value changed function: fampEditField
        function fampEditFieldValueChanged(app, event)
            app.famp = app.fampEditField.Value;            
        end

        % Value changed function: sigEditField
        function sigEditFieldValueChanged(app, event)
            app.sig = app.sigEditField.Value;          
        end

        % Value changed function: slrateEditField
        function slrateEditFieldValueChanged(app, event)
            app.slrate = app.slrateEditField.Value;           
        end

        % Button pushed function: VerEspectrosButton
        function VerEspectrosButtonPushed(app, event)
            app.VerEspectrosButton.Enable = 'off';
            app.mapp.sapp2 = RMNPulsoGrafEsp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppSSCPulsoRMN_Real = uifigure('Visible', 'off');
            app.AppSSCPulsoRMN_Real.Name = 'SSC Pulso RMN - Señal Real';
            app.AppSSCPulsoRMN_Real.CloseRequestFcn = createCallbackFcn(app, @AppSSCPulsoRMN_RealCloseRequest, true);
            app.UIAxesSIn = uiaxes(app.AppSSCPulsoRMN_Real);
            title(app.UIAxesSIn, 'Señal de Entrada')
            xlabel(app.UIAxesSIn, 't')
            ylabel(app.UIAxesSIn, 'Amplitud Normalizada')
            app.UIAxesSIn.YLim = [-1 1];
            app.UIAxesSIn.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesSIn.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxesSDerInt = uiaxes(app.AppSSCPulsoRMN_Real);
            title(app.UIAxesSDerInt, 'Derivada (azul) / Integral (verde)')
            xlabel(app.UIAxesSDerInt, 't')
            ylabel(app.UIAxesSDerInt, 'Amplitud Normalizada')
            app.UIAxesSDerInt.YLim = [-1 1];
            app.UIAxesSDerInt.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesSDerInt.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.PanelDer = uipanel(app.AppSSCPulsoRMN_Real);
            app.GeneraDerivadaButton = uibutton(app.PanelDer, 'push');
            app.GeneraDerivadaButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraDerivadaButtonPushed, true);
            app.GeneraDerivadaButton.Text = 'Genera Derivada';
            app.RadiodeFiltroMedianaLabel = uilabel(app.PanelDer);
            app.RadiodeFiltroMedianaLabel.Text = 'Radio de Filtro Mediana ';
            app.rFMaDerEditField = uieditfield(app.PanelDer, 'numeric');
            app.rFMaDerEditField.Limits = [1 100];
            app.rFMaDerEditField.RoundFractionalValues = 'on';
            app.rFMaDerEditField.ValueDisplayFormat = '%.0f';
            app.rFMaDerEditField.Value = 10;
            app.ActivarFiltroMedianaLabel = uilabel(app.PanelDer);
            app.ActivarFiltroMedianaLabel.Text = {'Activar Filtro Mediana'; ''};
            app.SwFMa = uiswitch(app.PanelDer, 'slider');
            app.SwFMa.Value = 'On';
            app.PanelSIn = uipanel(app.AppSSCPulsoRMN_Real);
            app.BorraSInButton = uibutton(app.PanelSIn, 'push');
            app.BorraSInButton.ButtonPushedFcn = createCallbackFcn(app, @BorraSInButtonPushed, true);
            app.BorraSInButton.Text = 'Borra Gráfico';
            app.SalirButton = uibutton(app.PanelSIn, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @AppSSCPulsoRMN_RealCloseRequest, true);
            app.SalirButton.Text = 'Salir';
            app.VerEntradaButton = uibutton(app.PanelSIn, 'push');
            app.VerEntradaButton.ButtonPushedFcn = createCallbackFcn(app, @VerEntradaButtonPushed, true);
            app.VerEntradaButton.Text = 'Ver Entrada';
            app.ActualizarEntradaButton = uibutton(app.PanelSIn, 'push');
            app.ActualizarEntradaButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarEntradaButtonPushed, true);
            app.ActualizarEntradaButton.Text = 'Actualizar Entrada';
            app.AplicarRuidoSwitchLabel = uilabel(app.PanelSIn);
            app.AplicarRuidoSwitchLabel.Text = 'Aplicar Ruido';
            app.SWAplicarRuido = uiswitch(app.PanelSIn, 'slider');
            app.SWAplicarRuido.Items = {'NO', 'SI'};
            app.SWAplicarRuido.Value = 'SI';
            app.PanelDetPul = uipanel(app.AppSSCPulsoRMN_Real);
            app.ToleranciaDeteccinPulsoLabel = uilabel(app.PanelDetPul);
            app.ToleranciaDeteccinPulsoLabel.Text = {'Tolerancia Detección'; 'Pulso [%]'};
            app.TolDefPulKnobAIn = uiknob(app.PanelDetPul, 'continuous');
            app.TolDefPulKnobAIn.Limits = [0 30];
            app.TolDefPulKnobAIn.ValueChangedFcn = createCallbackFcn(app, @TolDefPulKnobAInValueChanged, true);
            app.TolDefPulKnobAIn.ValueChangingFcn = createCallbackFcn(app, @TolDefPulKnobAInValueChanging, true);
            app.TolDefPulKnobAIn.Value = 3;
            app.TolDefPulKnobDIn = uieditfield(app.PanelDetPul, 'numeric');
            app.TolDefPulKnobDIn.Limits = [0 30];
            app.TolDefPulKnobDIn.ValueDisplayFormat = '%.2f';
            app.TolDefPulKnobDIn.ValueChangedFcn = createCallbackFcn(app, @TolDefPulKnobDInValueChanged, true);
            app.TolDefPulKnobDIn.Value = 3;
            app.DetPulButton = uibutton(app.PanelDetPul, 'push');
            app.DetPulButton.ButtonPushedFcn = createCallbackFcn(app, @DetPulButtonPushed, true);
            app.DetPulButton.Text = 'Detecta Pulso';
            app.tiniLabel = uilabel(app.PanelDetPul);
            app.tiniLabel.Text = 't ini:';
            app.tiniDisp = uieditfield(app.PanelDetPul, 'numeric');
            app.tiniDisp.Limits = [0 Inf];
            app.tiniDisp.ValueDisplayFormat = '%.2f';
            app.tiniDisp.Editable = 'off';
            app.tfinEditFieldLabel = uilabel(app.PanelDetPul);
            app.tfinEditFieldLabel.Text = 't fin:';
            app.tfinDisp = uieditfield(app.PanelDetPul, 'numeric');
            app.tfinDisp.Limits = [0 Inf];
            app.tfinDisp.ValueDisplayFormat = '%.2f';
            app.tfinDisp.Editable = 'off';
            app.tfinDisp.Value = 1;
            app.PanelSSC = uipanel(app.AppSSCPulsoRMN_Real);
            app.CalculaSSCButton = uibutton(app.PanelSSC, 'push');
            app.CalculaSSCButton.ButtonPushedFcn = createCallbackFcn(app, @CalculaSSCButtonPushed, true);
            app.CalculaSSCButton.Text = 'Calcula SSC';
            app.EntradaLabel = uilabel(app.PanelSSC);
            app.EntradaLabel.Text = 'Entrada';
            app.SWVerExtIn = uiswitch(app.PanelSSC, 'slider');
            app.SWVerExtIn.Items = {'NO', 'SI'};
            app.SWVerExtIn.Value = 'SI';
            app.DerivadaLabel = uilabel(app.PanelSSC);
            app.DerivadaLabel.Text = 'Derivada';
            app.SWVerExtDer = uiswitch(app.PanelSSC, 'slider');
            app.SWVerExtDer.Items = {'NO', 'SI'};
            app.SWVerExtDer.Value = 'SI';
            app.RadiodeDeteccindeExtremosLabel = uilabel(app.PanelSSC);
            app.RadiodeDeteccindeExtremosLabel.Text = {'Radio de Detección'; 'de Extremos'};
            app.rnExtEditField = uieditfield(app.PanelSSC, 'numeric');
            app.rnExtEditField.Limits = [0 Inf];
            app.rnExtEditField.RoundFractionalValues = 'on';
            app.rnExtEditField.ValueDisplayFormat = '%.0f';
            app.rnExtEditField.ValueChangedFcn = createCallbackFcn(app, @rnExtEditFieldValueChanged, true);
            app.rnExtEditField.Value = 5;
            app.IntegralLabel = uilabel(app.PanelSSC);
            app.IntegralLabel.Text = 'Integral';
            app.SWVerExtInt = uiswitch(app.PanelSSC, 'slider');
            app.SWVerExtInt.Items = {'NO', 'SI'};
            app.SWVerExtInt.Value = 'SI';
            app.TableSSC = uitable(app.PanelSSC);
            app.TableSSC.ColumnName = {'SSC'; 'Integral'; 'Señal'; 'Derivada'};
            app.TableSSC.RowName = {};
            app.HALTLabel = uilabel(app.PanelSSC);
            app.HALTLabel.Text = 'HALT';
            app.LEDSSC = uilamp(app.PanelSSC);
            app.CopyClipButton = uibutton(app.PanelSSC, 'push');
            app.CopyClipButton.ButtonPushedFcn = createCallbackFcn(app, @CopyClipButtonPushed, true);
            app.CopyClipButton.Text = 'Copiar al Clipboard';
            app.DecimalesLabel = uilabel(app.PanelSSC);
            app.DecimalesLabel.Text = 'Decimales';
            app.DeciEditField = uieditfield(app.PanelSSC, 'numeric');
            app.DeciEditField.Limits = [0 10];
            app.DeciEditField.ValueDisplayFormat = '%.0f';
            app.DeciEditField.Value = 4;
            app.VerExtremosLabel = uilabel(app.PanelSSC);
            app.VerExtremosLabel.Text = 'Ver Extremos';
            app.DetieneCicloButton = uibutton(app.PanelSSC, 'push');
            app.DetieneCicloButton.ButtonPushedFcn = createCallbackFcn(app, @DetieneCicloButtonPushed, true);
            app.DetieneCicloButton.Visible = 'off';
            app.DetieneCicloButton.Text = 'Detiene Ciclo';
            app.EjecutaCicloButton = uibutton(app.PanelSSC, 'push');
            app.EjecutaCicloButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutaCicloButtonPushed, true);
            app.EjecutaCicloButton.Text = 'Ejecuta Ciclo';
            app.NrodeiteracionesEditFieldLabel = uilabel(app.PanelSSC);
            app.NrodeiteracionesEditFieldLabel.Text = {'Nro. de'; 'iteraciones'};
            app.NCMaxEditField = uieditfield(app.PanelSSC, 'numeric');
            app.NCMaxEditField.Limits = [0 Inf];
            app.NCMaxEditField.RoundFractionalValues = 'on';
            app.NCMaxEditField.ValueDisplayFormat = '%.0f';
            app.NCMaxEditField.ValueChangedFcn = createCallbackFcn(app, @NCMaxEditFieldValueChanged, true);
            app.NCMaxEditField.Value = 1000;
            app.IteracinactualEditFieldLabel = uilabel(app.PanelSSC);
            app.IteracinactualEditFieldLabel.Text = {'Iteración'; 'actual'};
            app.NCActEditField = uieditfield(app.PanelSSC, 'numeric');
            app.NCActEditField.Limits = [0 Inf];
            app.NCActEditField.RoundFractionalValues = 'on';
            app.NCActEditField.ValueDisplayFormat = '%.0f';
            app.NCActEditField.Editable = 'off';
            app.GrabaOndaSwitchLabel = uilabel(app.PanelSSC);
            app.GrabaOndaSwitchLabel.Text = 'Graba Onda';
            app.GrabaOndaSwitch = uiswitch(app.PanelSSC, 'slider');
            app.GrabaOndaSwitch.Items = {'NO', 'SI'};
            app.GrabaOndaSwitch.Value = 'SI';
            app.PanelInt = uipanel(app.AppSSCPulsoRMN_Real);
            app.GeneraIntegralButton = uibutton(app.PanelInt, 'push');
            app.GeneraIntegralButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraIntegralButtonPushed, true);
            app.GeneraIntegralButton.Text = 'Genera Integral';
            app.PanelDICom = uipanel(app.AppSSCPulsoRMN_Real);
            app.BorraSDerIntButton = uibutton(app.PanelDICom, 'push');
            app.BorraSDerIntButton.ButtonPushedFcn = createCallbackFcn(app, @BorraSDerIntButtonPushed, true);
            app.BorraSDerIntButton.Text = 'Borra Gráfico';
            app.Panel = uipanel(app.AppSSCPulsoRMN_Real);
            app.RuidoBKnob = uiknob(app.Panel, 'continuous');
            app.RuidoBKnob.Limits = [0 50];
            app.RuidoBKnob.ValueChangedFcn = createCallbackFcn(app, @RuidoBKnobValueChanged, true);
            app.RuidoBKnob.ValueChangingFcn = createCallbackFcn(app, @RuidoBKnobValueChanging, true);
            app.RuidoBKnob.Value = 10;
            app.NrodepuntosLabel = uilabel(app.Panel);
            app.NrodepuntosLabel.Text = 'Nro. de puntos';
            app.NInEditField = uieditfield(app.Panel, 'numeric');
            app.NInEditField.Limits = [0 Inf];
            app.NInEditField.RoundFractionalValues = 'on';
            app.NInEditField.ValueDisplayFormat = '%.0f';
            app.NInEditField.Editable = 'off';
            app.NuevoMuestreoLabel = uilabel(app.Panel);
            app.NuevoMuestreoLabel.Text = 'Nuevo Muestreo';
            app.FaseLabel = uilabel(app.Panel);
            app.FaseLabel.Text = 'Fase';
            app.MuestreoFaseEditField = uieditfield(app.Panel, 'numeric');
            app.MuestreoFaseEditField.Limits = [0 1000];
            app.MuestreoFaseEditField.RoundFractionalValues = 'on';
            app.MuestreoFaseEditField.ValueDisplayFormat = '%.0f';
            app.MuestreoFaseEditField.ValueChangedFcn = createCallbackFcn(app, @MuestreoFaseEditFieldValueChanged, true);
            app.MuestreoFaseEditField.Value = 3;
            app.PasoLabel = uilabel(app.Panel);
            app.PasoLabel.Text = 'Paso';
            app.MuestreoPasoEditField = uieditfield(app.Panel, 'numeric');
            app.MuestreoPasoEditField.Limits = [0 1000];
            app.MuestreoPasoEditField.RoundFractionalValues = 'on';
            app.MuestreoPasoEditField.ValueDisplayFormat = '%.0f';
            app.MuestreoPasoEditField.ValueChangedFcn = createCallbackFcn(app, @MuestreoPasoEditFieldValueChanged, true);
            app.MuestreoPasoEditField.Value = 10;
            app.RuidoEditFieldLabel = uilabel(app.Panel);
            app.RuidoEditFieldLabel.Text = 'Ruido [%]';
            app.RuidoBEditField = uieditfield(app.Panel, 'numeric');
            app.RuidoBEditField.Limits = [0 50];
            app.RuidoBEditField.ValueDisplayFormat = '%.1f';
            app.RuidoBEditField.ValueChangedFcn = createCallbackFcn(app, @RuidoBEditFieldValueChanged, true);
            app.RuidoBEditField.Value = 10;
            app.AplicarRuidoBlancoSwitchLabel = uilabel(app.Panel);
            app.AplicarRuidoBlancoSwitchLabel.Text = 'Aplicar Ruido Blanco';
            app.SWAplicarRuidoB = uiswitch(app.Panel, 'slider');
            app.SWAplicarRuidoB.Items = {'NO', 'SI'};
            app.SWAplicarRuidoB.Value = 'SI';
            app.AplicarRuidoglitchSwitchLabel = uilabel(app.Panel);
            app.AplicarRuidoglitchSwitchLabel.Text = 'Aplicar Ruido glitch';
            app.SWAplicarRuidoG = uiswitch(app.Panel, 'slider');
            app.SWAplicarRuidoG.Items = {'NO', 'SI'};
            app.SWAplicarRuidoG.Value = 'SI';
            app.FrecEditFieldLabel = uilabel(app.Panel);
            app.FrecEditFieldLabel.Text = 'Frec. ';
            app.RuidoGFrecEditField = uieditfield(app.Panel, 'numeric');
            app.RuidoGFrecEditField.Limits = [0 Inf];
            app.RuidoGFrecEditField.ValueChangedFcn = createCallbackFcn(app, @RuidoGFrecEditFieldValueChanged, true);
            app.RuidoGFrecEditField.Value = 1000;
            app.AmpEditFieldLabel = uilabel(app.Panel);
            app.AmpEditFieldLabel.Text = 'Amp. [%]';
            app.RuidoGAmpEditField = uieditfield(app.Panel, 'numeric');
            app.RuidoGAmpEditField.Limits = [0 Inf];
            app.RuidoGAmpEditField.ValueChangedFcn = createCallbackFcn(app, @RuidoGAmpEditFieldValueChanged, true);
            app.RuidoGAmpEditField.Value = 25;
            app.AtenuacinmxLabel = uilabel(app.Panel);
            app.AtenuacinmxLabel.Text = {'Atenuación'; 'máx.[%]'};
            app.AtenMaxEditField = uieditfield(app.Panel, 'numeric');
            app.AtenMaxEditField.Limits = [0 100];
            app.AtenMaxEditField.ValueChangedFcn = createCallbackFcn(app, @AtenMaxEditFieldValueChanged, true);
            app.AtenMaxEditField.Value = 25;
            app.OffSetmxLabel = uilabel(app.Panel);
            app.OffSetmxLabel.Text = {'OffSet'; 'máx. [%]'};
            app.OffSetMaxEditField = uieditfield(app.Panel, 'numeric');
            app.OffSetMaxEditField.Limits = [0 20];
            app.OffSetMaxEditField.ValueChangedFcn = createCallbackFcn(app, @OffSetMaxEditFieldValueChanged, true);
            app.OffSetMaxEditField.Value = 5;
            app.PanelFiltroPulso = uipanel(app.AppSSCPulsoRMN_Real);
            app.AplicarFiltroMedianaLabel = uilabel(app.PanelFiltroPulso);
            app.AplicarFiltroMedianaLabel.Text = 'Aplicar Filtro Mediana';
            app.SWAplicarFMediana = uiswitch(app.PanelFiltroPulso, 'slider');
            app.SWAplicarFMediana.Items = {'NO', 'SI'};
            app.SWAplicarFMediana.Value = 'SI';
            app.AplicarFiltroMediaLabel = uilabel(app.PanelFiltroPulso);
            app.AplicarFiltroMediaLabel.Text = 'Aplicar Filtro Media';
            app.SWAplicarFMedia = uiswitch(app.PanelFiltroPulso, 'slider');
            app.SWAplicarFMedia.Items = {'NO', 'SI'};
            app.SWAplicarFMedia.Value = 'SI';
            app.RadioFMedianaLabel = uilabel(app.PanelFiltroPulso);
            app.RadioFMedianaLabel.Text = {'Radio F.'; 'Mediana'};
            app.rFMaEditField = uieditfield(app.PanelFiltroPulso, 'numeric');
            app.rFMaEditField.Limits = [0 Inf];
            app.rFMaEditField.RoundFractionalValues = 'on';
            app.rFMaEditField.ValueDisplayFormat = '%.0f';
            app.rFMaEditField.ValueChangedFcn = createCallbackFcn(app, @rFMaEditFieldValueChanged, true);
            app.rFMaEditField.Value = 2;
            app.RadioFMediaLabel = uilabel(app.PanelFiltroPulso);
            app.RadioFMediaLabel.Text = {'Radio F.'; 'Media'};
            app.rFMeEditField = uieditfield(app.PanelFiltroPulso, 'numeric');
            app.rFMeEditField.Limits = [0 Inf];
            app.rFMeEditField.RoundFractionalValues = 'on';
            app.rFMeEditField.ValueDisplayFormat = '%.0f';
            app.rFMeEditField.ValueChangedFcn = createCallbackFcn(app, @rFMeEditFieldValueChanged, true);
            app.rFMeEditField.Value = 5;
            app.NrodepuntosInicioFinLabel = uilabel(app.PanelFiltroPulso);
            app.NrodepuntosInicioFinLabel.Text = {'Nro. de puntos'; 'Inicio/Fin'};
            app.nOffSEditField = uieditfield(app.PanelFiltroPulso, 'numeric');
            app.nOffSEditField.Limits = [0 Inf];
            app.nOffSEditField.RoundFractionalValues = 'on';
            app.nOffSEditField.ValueDisplayFormat = '%.0f';
            app.nOffSEditField.ValueChangedFcn = createCallbackFcn(app, @nOffSEditFieldValueChanged, true);
            app.nOffSEditField.Value = 10;
            app.CorregirOffSetAmpLabel = uilabel(app.PanelFiltroPulso);
            app.CorregirOffSetAmpLabel.Text = 'Corregir OffSet/Amp.';
            app.SWCorrOffSAmp = uiswitch(app.PanelFiltroPulso, 'slider');
            app.SWCorrOffSAmp.Items = {'NO', 'SI'};
            app.SWCorrOffSAmp.Value = 'SI';
            app.PanelDeformaEsp = uipanel(app.AppSSCPulsoRMN_Real);
            app.DeformacinEspectralLabel = uilabel(app.PanelDeformaEsp);
            app.DeformacinEspectralLabel.Text = 'Deformación Espectral';
            app.TipoDefEspKnob = uiknob(app.PanelDeformaEsp, 'discrete');
            app.TipoDefEspKnob.Items = {'NO', 'GaussBurn', 'PB'};
            app.TipoDefEspKnob.ValueChangedFcn = createCallbackFcn(app, @TipoDefEspKnobValueChanged, true);
            app.TipoDefEspKnob.Value = 'NO';
            app.ffilLabel = uilabel(app.PanelDeformaEsp);
            app.ffilLabel.Text = 'ffil';
            app.ffilEditField = uieditfield(app.PanelDeformaEsp, 'numeric');
            app.ffilEditField.Limits = [0 Inf];
            app.ffilEditField.ValueDisplayFormat = '%.2f';
            app.ffilEditField.ValueChangedFcn = createCallbackFcn(app, @ffilEditFieldValueChanged, true);
            app.ParmetrosLabel = uilabel(app.PanelDeformaEsp);
            app.ParmetrosLabel.Text = 'Parámetros';
            app.sigEditFieldLabel = uilabel(app.PanelDeformaEsp);
            app.sigEditFieldLabel.Text = 'sig';
            app.sigEditField = uieditfield(app.PanelDeformaEsp, 'numeric');
            app.sigEditField.Limits = [0 Inf];
            app.sigEditField.ValueDisplayFormat = '%.2f';
            app.sigEditField.ValueChangedFcn = createCallbackFcn(app, @sigEditFieldValueChanged, true);
            app.sigEditField.Value = 1;
            app.slrateEditFieldLabel = uilabel(app.PanelDeformaEsp);
            app.slrateEditFieldLabel.Text = 'slrate';
            app.slrateEditField = uieditfield(app.PanelDeformaEsp, 'numeric');
            app.slrateEditField.Limits = [0 Inf];
            app.slrateEditField.ValueDisplayFormat = '%.2f';
            app.slrateEditField.ValueChangedFcn = createCallbackFcn(app, @slrateEditFieldValueChanged, true);
            app.slrateEditField.Value = 1;
            app.fampEditFieldLabel = uilabel(app.PanelDeformaEsp);
            app.fampEditFieldLabel.Text = 'famp';
            app.fampEditField = uieditfield(app.PanelDeformaEsp, 'numeric');
            app.fampEditField.Limits = [0 1];
            app.fampEditField.ValueDisplayFormat = '%.2f';
            app.fampEditField.ValueChangedFcn = createCallbackFcn(app, @fampEditFieldValueChanged, true);
            app.fampEditField.Value = 1;
            app.dimFFTEditFieldLabel = uilabel(app.PanelDeformaEsp);
            app.dimFFTEditFieldLabel.Text = 'dimFFT';
            app.dimFFTEditField = uieditfield(app.PanelDeformaEsp, 'numeric');
            app.dimFFTEditField.Limits = [0 Inf];
            app.dimFFTEditField.RoundFractionalValues = 'on';
            app.dimFFTEditField.ValueDisplayFormat = '%.0f';
            app.dimFFTEditField.ValueChangedFcn = createCallbackFcn(app, @dimFFTEditFieldValueChanged, true);
            app.dimFFTEditField.Value = 65536;
            app.VerEspectrosButton = uibutton(app.PanelDeformaEsp, 'push');
            app.VerEspectrosButton.ButtonPushedFcn = createCallbackFcn(app, @VerEspectrosButtonPushed, true);
            app.VerEspectrosButton.Text = 'Ver Espectros';
            % Show the figure after all components are created
            app.AppSSCPulsoRMN_Real.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMNPulsoRealSSC_GN_DL(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppSSCPulsoRMN_Real)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppSSCPulsoRMN_Real)
        end
    end
end